classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        RunButton                matlab.ui.control.Button
        LimitEditFieldLabel      matlab.ui.control.Label
        FrequencyEditFieldLabel  matlab.ui.control.Label
        LimitEditField           matlab.ui.control.NumericEditField
        FrequencyEditField       matlab.ui.control.NumericEditField
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        InputShaperTestLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                    ModelName = 'My_model';
                    simInp = Simulink.SimulationInput(ModelName);
                    simInp = simInp.setVariable('amp',app.AmplitudeEditField.Value);
                    simInp = simInp.setVariable('freq',app.FrequencyEditField.Value);
                    simInp = simInp.setVariable('lim',app.LimitEditField.Value);
                    stopTimestr = num2str(app.LimitEditField.Value);
                    simInp = simInp.setModelParameter('stopTime',stopTimestr);% misunderstand
                    simInp = simInp.setModelParameter('simulationMode','Rapid');
                    simInp = simulink.compiler.configureForDeployment(simInp);
                    simOut = sim(simInp);
                    time = simOut.tout;
                    signal = simOut.yout{1}.Values.Data;
                    acc = simOut.yout{2}.Values.Data;
                    vel = simOut.yout{3}.Values.Data;
                    position = simOut.yout{3}.Values.Data;
                    plot(app.UIAxes,time,squeeze(signal));
                    plot(app.UIAxes2,time,squeeze(acc));
                    plot(app.UIAxes3,time,squeeze(vel));
                   plot(app.UIAxes4,time,squeeze(position));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputShaperTestLabel = uilabel(app.GridLayout);
            app.InputShaperTestLabel.Text = 'Input Shaper Test';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Input SIgnal')
            xlabel(app.UIAxes, 'TIme')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Position')
            xlabel(app.UIAxes4, 'Time')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Velocity')
            xlabel(app.UIAxes3, 'TIme')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Acceleration')
            xlabel(app.UIAxes2, 'TIme')
            zlabel(app.UIAxes2, 'Z')
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'modelpng.png';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.AmplitudeEditFieldLabel = uilabel(app.GridLayout3);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FrequencyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LimitEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.GridLayout3);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.LimitEditFieldLabel = uilabel(app.GridLayout3);
            app.LimitEditFieldLabel.Text = 'Limit';
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end